classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure              matlab.ui.Figure
        edit1               matlab.ui.control.EditField
        edit2               matlab.ui.control.EditField
        text3               matlab.ui.control.Label
        text4               matlab.ui.control.Label
        pushbutton1         matlab.ui.control.Button
        Image               matlab.ui.control.Image
        text6               matlab.ui.control.Label
        text3_2             matlab.ui.control.Label
        RememberMeCheckBox  matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function main_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to main (see VARARGIN)
%             
%             % Choose default command line output for main
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit2 as text
            %        str2double(get(hObject,'String')) returns contents of edit2 as a double
            global pw
            pw = get(app.edit2);
            pw = convertCharsToStrings(pw.Value);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global pw
            if strcmp(pw, "salleriscool")
                app.text6.Text = "";
                close(app.figure)
                main_dark
            else
                app.text6.Text = "Incorrect Username and/or Password";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure = uifigure('Visible', 'off');
            app.figure.Name = 'Mars Project Information System';
            app.edit1 = uieditfield(app.figure, 'text');
            app.edit2 = uieditfield(app.figure, 'text');
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.text3 = uilabel(app.figure);
            app.text3.Text = 'Username or Email Address';
            app.text4 = uilabel(app.figure);
            app.text4.Text = 'Password';
            app.pushbutton1 = uibutton(app.figure, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Text = 'Access';
            app.text6 = uilabel(app.figure);
            app.text6.Text = '';
            app.Image = uiimage(app.figure);
            app.Image.ImageSource = 'login_logo.svg';
            app.text3_2 = uilabel(app.figure);
            app.text3_2.Text = 'Forgot password?';
            app.RememberMeCheckBox = uicheckbox(app.figure);
            app.RememberMeCheckBox.Text = 'Remember Me';
            % Show the figure after all components are created
            app.figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure)

            % Execute the startup function
            runStartupFcn(app, @main_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure)
        end
    end
end